classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectImageButton            matlab.ui.control.Button
        ImageSelectedEditFieldLabel  matlab.ui.control.Label
        ImageSelectedEditField       matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        DecryptButton                matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file;
        path;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              drawnow;
            app.UIFigure.WindowState = 'maximized';

        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.tif');
            drawnow;
            figure(app.UIFigure);
            app.ImageSelectedEditField.Value = fullfile(app.path,app.file);
            Image1 = imread(fullfile(app.path,app.file));
            imshow(Image1,'Parent',app.UIAxes);
            app.UIAxes.XLabel.String = '';
            app.UIAxes.Title.String = '';
            app.UIAxes.YLabel.String = '';
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            TO_WAV(app.path,app.file);
            y = audioread(fullfile(app.path,'Recording2.wav'));
            plot(app.UIAxes2,y);
            app.UIAxes2.Title.String = 'Decrypted Waveform';
            app.UIAxes2.Title.FontSize = 16;
            app.UIAxes2.XLabel.String = 'Samples';
            app.UIAxes2.XLabel.FontSize = 14;
            app.UIAxes2.YLabel.String = 'Amplitude';
            app.UIAxes2.YLabel.FontSize = 14;
            msgbox('Decrypted Successfully!!','Success','help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.ImageSelectedEditFieldLabel.Text = 'Image Selected:';
            app.ImageSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DecryptButton = uibutton(app.UIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end